classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ModelPanel                matlab.ui.container.Panel
        AlphaSliderLabel          matlab.ui.control.Label
        AlphaSlider               matlab.ui.control.Slider
        AlphaFieldEditFieldLabel  matlab.ui.control.Label
        AlphaFieldEditField       matlab.ui.control.NumericEditField
        BetaSliderLabel           matlab.ui.control.Label
        BetaSlider                matlab.ui.control.Slider
        BetaFieldEditFieldLabel   matlab.ui.control.Label
        BetaFieldEditField        matlab.ui.control.NumericEditField
        OutputPanel               matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        ShowDropDownLabel         matlab.ui.control.Label
        ShowDropDown              matlab.ui.control.DropDown
    end


    properties (Access = public)
        params = ModelParameters()
        results = ModelResults()
        % TODO: add a ModelPlotter or something?
        selectedField = 'Output1' % for now, selected field to plot
    end

    methods (Access = private)
        function redraw(app)
            disp("redrawing app...");
            plotModel(app.UIAxes, app.selectedField, app.results)
        end
    
        function run(app) 
            app.results = runModel(app.params);
        end
    
        function runAndRedraw(app)
            app.run();
            app.redraw();
        end
    
    end


    methods (Access = private)

        % Value changed function: AlphaSlider
        function AlphaSliderValueChanged(app, event)
            value = app.AlphaSlider.Value;
            set(app.AlphaFieldEditField,'Value',value);
            app.params.a = value; 
            app.runAndRedraw();
        end

        % Value changed function: BetaSlider
        function BetaSliderValueChanged(app, event)
            value = app.BetaSlider.Value;
            set(app.BetaFieldEditField,'Value',value);
            app.params.b = value; 
            app.runAndRedraw();
        end

        % Value changed function: ShowDropDown
        function ShowDropDownValueChanged(app, event)
            app.selectedField = app.ShowDropDown.Value;
            app.redraw();
        end

        % Value changed function: AlphaFieldEditField
        function AlphaFieldEditFieldValueChanged(app, event)
            value = app.AlphaFieldEditField.Value;
            set(app.AlphaSlider, 'Value', value);
            app.params.a = value;
            app.runAndRedraw();
        end

        % Value changed function: BetaFieldEditField
        function BetaFieldEditFieldValueChanged(app, event)
            value = app.BetaFieldEditField.Value;
            set(app.BetaSlider, 'Value', value);
            app.params.b = value;
            app.runAndRedraw();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ModelPanel = uipanel(app.UIFigure);
            app.ModelPanel.Title = 'Model';
            app.AlphaSliderLabel = uilabel(app.ModelPanel);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.ModelPanel);
            app.AlphaSlider.Limits = [0 1];
            app.AlphaSlider.MajorTicks = [0 0.125 0.25 0.375 0.5 0.625 0.75 0.875 1];
            app.AlphaSlider.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaSliderValueChanged, true);
            app.AlphaFieldEditFieldLabel = uilabel(app.ModelPanel);
            app.AlphaFieldEditFieldLabel.Text = 'AlphaField';
            app.AlphaFieldEditField = uieditfield(app.ModelPanel, 'numeric');
            app.AlphaFieldEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaFieldEditFieldValueChanged, true);
            app.BetaSliderLabel = uilabel(app.ModelPanel);
            app.BetaSliderLabel.Text = 'Beta';
            app.BetaSlider = uislider(app.ModelPanel);
            app.BetaSlider.Limits = [0 1];
            app.BetaSlider.MajorTicks = [0 0.125 0.25 0.375 0.5 0.625 0.75 0.875 1];
            app.BetaSlider.MajorTickLabels = {'', '', '', '', '', '', '', '', ''};
            app.BetaSlider.ValueChangedFcn = createCallbackFcn(app, @BetaSliderValueChanged, true);
            app.BetaFieldEditFieldLabel = uilabel(app.ModelPanel);
            app.BetaFieldEditFieldLabel.Text = 'BetaField';
            app.BetaFieldEditField = uieditfield(app.ModelPanel, 'numeric');
            app.BetaFieldEditField.ValueChangedFcn = createCallbackFcn(app, @BetaFieldEditFieldValueChanged, true);
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.UIAxes = uiaxes(app.OutputPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ShowDropDownLabel = uilabel(app.OutputPanel);
            app.ShowDropDownLabel.Text = 'Show:';
            app.ShowDropDown = uidropdown(app.OutputPanel);
            app.ShowDropDown.Items = {'Output1', 'Output2'};
            app.ShowDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowDropDownValueChanged, true);
            app.ShowDropDown.Value = 'Output1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end